classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SensoresfuerzaPanel       matlab.ui.container.Panel
        SensoresfuerzaPanel_2     matlab.ui.container.Panel
        PieizquierdoPanel         matlab.ui.container.Panel
        Sensor4EditField_2        matlab.ui.control.NumericEditField
        Sensor4EditField_2Label   matlab.ui.control.Label
        Sensor3EditField_2        matlab.ui.control.NumericEditField
        Sensor3EditField_2Label   matlab.ui.control.Label
        Sensor2EditField_2        matlab.ui.control.NumericEditField
        Sensor2EditField_2Label   matlab.ui.control.Label
        Sensor1EditField_5        matlab.ui.control.NumericEditField
        Sensor1EditField_5Label   matlab.ui.control.Label
        PiederechoPanel           matlab.ui.container.Panel
        Sensor4EditField          matlab.ui.control.NumericEditField
        Sensor4EditFieldLabel     matlab.ui.control.Label
        Sensor3EditField          matlab.ui.control.NumericEditField
        Sensor3EditFieldLabel     matlab.ui.control.Label
        Sensor2EditField          matlab.ui.control.NumericEditField
        Sensor2EditFieldLabel     matlab.ui.control.Label
        Sensor1EditField          matlab.ui.control.NumericEditField
        Sensor1EditFieldLabel     matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        ServomotoresProbicPanel   matlab.ui.container.Panel
        EnviarButton              matlab.ui.control.Button
        PiernaizquierdaLabel      matlab.ui.control.Label
        PiernaderechaLabel        matlab.ui.control.Label
        Servo12Panel              matlab.ui.container.Panel
        ActuadorEditField_12      matlab.ui.control.NumericEditField
        SensorLabel_12            matlab.ui.control.Label
        AcciondecontrolLabel_12   matlab.ui.control.Label
        PosicionrealEditField_12  matlab.ui.control.NumericEditField
        Servo11Panel              matlab.ui.container.Panel
        ActuadorEditField_11      matlab.ui.control.NumericEditField
        SensorLabel_11            matlab.ui.control.Label
        AcciondecontrolLabel_11   matlab.ui.control.Label
        PosicionrealEditField_11  matlab.ui.control.NumericEditField
        Servo10Panel              matlab.ui.container.Panel
        ActuadorEditField_10      matlab.ui.control.NumericEditField
        SensorLabel_10            matlab.ui.control.Label
        AcciondecontrolLabel_10   matlab.ui.control.Label
        PosicionrealEditField_10  matlab.ui.control.NumericEditField
        Servo9Panel               matlab.ui.container.Panel
        ActuadorEditField_9       matlab.ui.control.NumericEditField
        SensorLabel_9             matlab.ui.control.Label
        AcciondecontrolLabel_9    matlab.ui.control.Label
        PosicionrealEditField_9   matlab.ui.control.NumericEditField
        Servo8Panel               matlab.ui.container.Panel
        ActuadorEditField_8       matlab.ui.control.NumericEditField
        SensorLabel_8             matlab.ui.control.Label
        AcciondecontrolLabel_8    matlab.ui.control.Label
        PosicionrealEditField_8   matlab.ui.control.NumericEditField
        Servo7Panel               matlab.ui.container.Panel
        ActuadorEditField_7       matlab.ui.control.NumericEditField
        SensorLabel_7             matlab.ui.control.Label
        AcciondecontrolLabel_7    matlab.ui.control.Label
        PosicionrealEditField_7   matlab.ui.control.NumericEditField
        Servo6Panel               matlab.ui.container.Panel
        ActuadorEditField_6       matlab.ui.control.NumericEditField
        SensorLabel_6             matlab.ui.control.Label
        AcciondecontrolLabel_6    matlab.ui.control.Label
        PosicionrealEditField_6   matlab.ui.control.NumericEditField
        Servo5Panel               matlab.ui.container.Panel
        ActuadorEditField_5       matlab.ui.control.NumericEditField
        SensorLabel_5             matlab.ui.control.Label
        AcciondecontrolLabel_5    matlab.ui.control.Label
        PosicionrealEditField_5   matlab.ui.control.NumericEditField
        Servo4Panel               matlab.ui.container.Panel
        ActuadorEditField_4       matlab.ui.control.NumericEditField
        SensorLabel_4             matlab.ui.control.Label
        AcciondecontrolLabel_4    matlab.ui.control.Label
        PosicionrealEditField_4   matlab.ui.control.NumericEditField
        Servo3Panel               matlab.ui.container.Panel
        ActuadorEditField_3       matlab.ui.control.NumericEditField
        SensorLabel_3             matlab.ui.control.Label
        AcciondecontrolLabel_3    matlab.ui.control.Label
        PosicionrealEditField_3   matlab.ui.control.NumericEditField
        Servo2Panel               matlab.ui.container.Panel
        ActuadorEditField_2       matlab.ui.control.NumericEditField
        SensorLabel_2             matlab.ui.control.Label
        AcciondecontrolLabel_2    matlab.ui.control.Label
        PosicionrealEditField_2   matlab.ui.control.NumericEditField
        Servo1Panel               matlab.ui.container.Panel
        ActuadorEditField_1       matlab.ui.control.NumericEditField
        SensorLabel_1             matlab.ui.control.Label
        AcciondecontrolLabel_1    matlab.ui.control.Label
        PosicionrealEditField_1   matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        Servos = [];
        Servo;
        response;
    end

    methods (Access = private)
        
        function results = setActuators(app)
            
            app.Servos = [];
            clear app.Servo;

            app.Servo.NumServo = 1;
            app.Servo.Actuator = round(app.ActuadorEditField_1.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 2;
            app.Servo.Actuator = round(app.ActuadorEditField_2.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 3;
            app.Servo.Actuator = round(app.ActuadorEditField_3.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 4;
            app.Servo.Actuator = round(app.ActuadorEditField_4.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 5;
            app.Servo.Actuator = round(app.ActuadorEditField_5.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 6;
            app.Servo.Actuator = round(app.ActuadorEditField_6.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 7;
            app.Servo.Actuator = round(app.ActuadorEditField_7.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 8;
            app.Servo.Actuator = round(app.ActuadorEditField_8.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 9;
            app.Servo.Actuator = round(app.ActuadorEditField_9.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 10;
            app.Servo.Actuator = round(app.ActuadorEditField_10.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 11;
            app.Servo.Actuator = round(app.ActuadorEditField_11.Value,2);
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 12;
            app.Servo.Actuator = round(app.ActuadorEditField_12.Value,2);
            app.Servos = [app.Servos app.Servo];
            
            clear app.response;

            try
                url = 'http://localhost:3000/setServosActuators';
                options = weboptions('MediaType', 'application/json');
                app.response = webwrite(url, jsonencode(app.Servos), options);
            catch 
            end
            
        end
        
        function results = getSensors(app)

            app.Servos = [];
            clear app.Servo;

            app.Servo.NumServo = 1;
            app.Servos = [app.Servos app.Servo];
            
            app.Servo.NumServo = 2;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 3;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 4;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 5;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 6;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 7;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 8;
            app.Servos = [app.Servos app.Servo];
            
            app.Servo.NumServo = 9;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 10;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 11;
            app.Servos = [app.Servos app.Servo];

            app.Servo.NumServo = 12;
            app.Servos = [app.Servos app.Servo];
            
            clear app.response;

            try
                url = 'http://localhost:3000/getServosSensors';
                options = weboptions('MediaType', 'application/json');
                app.response = webwrite(url, jsonencode(app.Servos), options);
            catch 
            end
            
            clear app.Servos;
            app.Servos = app.response;

            app.PosicionrealEditField_1.Value = app.Servos(1).Sensor;
            app.PosicionrealEditField_2.Value = app.Servos(2).Sensor;
            app.PosicionrealEditField_3.Value = app.Servos(3).Sensor;
            app.PosicionrealEditField_4.Value = app.Servos(4).Sensor;
            app.PosicionrealEditField_5.Value = app.Servos(5).Sensor;
            app.PosicionrealEditField_6.Value = app.Servos(6).Sensor;
            app.PosicionrealEditField_7.Value = app.Servos(7).Sensor;
            app.PosicionrealEditField_8.Value = app.Servos(8).Sensor;
            app.PosicionrealEditField_9.Value = app.Servos(9).Sensor;
            app.PosicionrealEditField_10.Value = app.Servos(10).Sensor;
            app.PosicionrealEditField_11.Value = app.Servos(11).Sensor;
            app.PosicionrealEditField_12.Value = app.Servos(12).Sensor;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            while true
                app.getSensors();
                pause(0.1);
            end
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            app.setActuators();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ServomotoresProbicPanel = uipanel(app.UIFigure);
            app.ServomotoresProbicPanel.Title = 'Servomotores Probic';
            app.Servo1Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo1Panel.Title = 'Servo 1';
            app.PosicionrealEditField_1 = uieditfield(app.Servo1Panel, 'numeric');
            app.PosicionrealEditField_1.Editable = 'off';
            app.AcciondecontrolLabel_1 = uilabel(app.Servo1Panel);
            app.AcciondecontrolLabel_1.Text = 'Accion de control';
            app.SensorLabel_1 = uilabel(app.Servo1Panel);
            app.SensorLabel_1.Text = 'Sensor';
            app.ActuadorEditField_1 = uieditfield(app.Servo1Panel, 'numeric');
            app.Servo2Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo2Panel.Title = 'Servo 2';
            app.PosicionrealEditField_2 = uieditfield(app.Servo2Panel, 'numeric');
            app.PosicionrealEditField_2.Editable = 'off';
            app.AcciondecontrolLabel_2 = uilabel(app.Servo2Panel);
            app.AcciondecontrolLabel_2.Text = 'Accion de control';
            app.SensorLabel_2 = uilabel(app.Servo2Panel);
            app.SensorLabel_2.Text = 'Sensor';
            app.ActuadorEditField_2 = uieditfield(app.Servo2Panel, 'numeric');
            app.Servo3Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo3Panel.Title = 'Servo 3';
            app.PosicionrealEditField_3 = uieditfield(app.Servo3Panel, 'numeric');
            app.PosicionrealEditField_3.Editable = 'off';
            app.AcciondecontrolLabel_3 = uilabel(app.Servo3Panel);
            app.AcciondecontrolLabel_3.Text = 'Accion de control';
            app.SensorLabel_3 = uilabel(app.Servo3Panel);
            app.SensorLabel_3.Text = 'Sensor';
            app.ActuadorEditField_3 = uieditfield(app.Servo3Panel, 'numeric');
            app.Servo4Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo4Panel.Title = 'Servo 4';
            app.PosicionrealEditField_4 = uieditfield(app.Servo4Panel, 'numeric');
            app.PosicionrealEditField_4.Editable = 'off';
            app.AcciondecontrolLabel_4 = uilabel(app.Servo4Panel);
            app.AcciondecontrolLabel_4.Text = 'Accion de control';
            app.SensorLabel_4 = uilabel(app.Servo4Panel);
            app.SensorLabel_4.Text = 'Sensor';
            app.ActuadorEditField_4 = uieditfield(app.Servo4Panel, 'numeric');
            app.Servo5Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo5Panel.Title = 'Servo 5';
            app.PosicionrealEditField_5 = uieditfield(app.Servo5Panel, 'numeric');
            app.PosicionrealEditField_5.Editable = 'off';
            app.AcciondecontrolLabel_5 = uilabel(app.Servo5Panel);
            app.AcciondecontrolLabel_5.Text = 'Accion de control';
            app.SensorLabel_5 = uilabel(app.Servo5Panel);
            app.SensorLabel_5.Text = 'Sensor';
            app.ActuadorEditField_5 = uieditfield(app.Servo5Panel, 'numeric');
            app.Servo6Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo6Panel.Title = 'Servo 6';
            app.PosicionrealEditField_6 = uieditfield(app.Servo6Panel, 'numeric');
            app.PosicionrealEditField_6.Editable = 'off';
            app.AcciondecontrolLabel_6 = uilabel(app.Servo6Panel);
            app.AcciondecontrolLabel_6.Text = 'Accion de control';
            app.SensorLabel_6 = uilabel(app.Servo6Panel);
            app.SensorLabel_6.Text = 'Sensor';
            app.ActuadorEditField_6 = uieditfield(app.Servo6Panel, 'numeric');
            app.Servo7Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo7Panel.Title = 'Servo 7';
            app.PosicionrealEditField_7 = uieditfield(app.Servo7Panel, 'numeric');
            app.PosicionrealEditField_7.Editable = 'off';
            app.AcciondecontrolLabel_7 = uilabel(app.Servo7Panel);
            app.AcciondecontrolLabel_7.Text = 'Accion de control';
            app.SensorLabel_7 = uilabel(app.Servo7Panel);
            app.SensorLabel_7.Text = 'Sensor';
            app.ActuadorEditField_7 = uieditfield(app.Servo7Panel, 'numeric');
            app.Servo8Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo8Panel.Title = 'Servo 8';
            app.PosicionrealEditField_8 = uieditfield(app.Servo8Panel, 'numeric');
            app.PosicionrealEditField_8.Editable = 'off';
            app.AcciondecontrolLabel_8 = uilabel(app.Servo8Panel);
            app.AcciondecontrolLabel_8.Text = 'Accion de control';
            app.SensorLabel_8 = uilabel(app.Servo8Panel);
            app.SensorLabel_8.Text = 'Sensor';
            app.ActuadorEditField_8 = uieditfield(app.Servo8Panel, 'numeric');
            app.Servo9Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo9Panel.Title = 'Servo 9';
            app.PosicionrealEditField_9 = uieditfield(app.Servo9Panel, 'numeric');
            app.PosicionrealEditField_9.Editable = 'off';
            app.AcciondecontrolLabel_9 = uilabel(app.Servo9Panel);
            app.AcciondecontrolLabel_9.Text = 'Accion de control';
            app.SensorLabel_9 = uilabel(app.Servo9Panel);
            app.SensorLabel_9.Text = 'Sensor';
            app.ActuadorEditField_9 = uieditfield(app.Servo9Panel, 'numeric');
            app.Servo10Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo10Panel.Title = 'Servo 10';
            app.PosicionrealEditField_10 = uieditfield(app.Servo10Panel, 'numeric');
            app.PosicionrealEditField_10.Editable = 'off';
            app.AcciondecontrolLabel_10 = uilabel(app.Servo10Panel);
            app.AcciondecontrolLabel_10.Text = 'Accion de control';
            app.SensorLabel_10 = uilabel(app.Servo10Panel);
            app.SensorLabel_10.Text = 'Sensor';
            app.ActuadorEditField_10 = uieditfield(app.Servo10Panel, 'numeric');
            app.Servo11Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo11Panel.Title = 'Servo 11';
            app.PosicionrealEditField_11 = uieditfield(app.Servo11Panel, 'numeric');
            app.PosicionrealEditField_11.Editable = 'off';
            app.AcciondecontrolLabel_11 = uilabel(app.Servo11Panel);
            app.AcciondecontrolLabel_11.Text = 'Accion de control';
            app.SensorLabel_11 = uilabel(app.Servo11Panel);
            app.SensorLabel_11.Text = 'Sensor';
            app.ActuadorEditField_11 = uieditfield(app.Servo11Panel, 'numeric');
            app.Servo12Panel = uipanel(app.ServomotoresProbicPanel);
            app.Servo12Panel.Title = 'Servo 12';
            app.PosicionrealEditField_12 = uieditfield(app.Servo12Panel, 'numeric');
            app.PosicionrealEditField_12.Editable = 'off';
            app.AcciondecontrolLabel_12 = uilabel(app.Servo12Panel);
            app.AcciondecontrolLabel_12.Text = 'Accion de control';
            app.SensorLabel_12 = uilabel(app.Servo12Panel);
            app.SensorLabel_12.Text = 'Sensor';
            app.ActuadorEditField_12 = uieditfield(app.Servo12Panel, 'numeric');
            app.PiernaderechaLabel = uilabel(app.ServomotoresProbicPanel);
            app.PiernaderechaLabel.Text = 'Pierna derecha';
            app.PiernaizquierdaLabel = uilabel(app.ServomotoresProbicPanel);
            app.PiernaizquierdaLabel.Text = 'Pierna izquierda';
            app.EnviarButton = uibutton(app.ServomotoresProbicPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.SensoresfuerzaPanel = uipanel(app.UIFigure);
            app.SensoresfuerzaPanel.Title = 'Sensores fuerza';
            app.UIAxes = uiaxes(app.SensoresfuerzaPanel);
            title(app.UIAxes, 'ZMP')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SensoresfuerzaPanel_2 = uipanel(app.SensoresfuerzaPanel);
            app.SensoresfuerzaPanel_2.Title = 'Sensores fuerza';
            app.PiederechoPanel = uipanel(app.SensoresfuerzaPanel_2);
            app.PiederechoPanel.Title = 'Pie derecho';
            app.Sensor1EditFieldLabel = uilabel(app.PiederechoPanel);
            app.Sensor1EditFieldLabel.Text = 'Sensor 1';
            app.Sensor1EditField = uieditfield(app.PiederechoPanel, 'numeric');
            app.Sensor2EditFieldLabel = uilabel(app.PiederechoPanel);
            app.Sensor2EditFieldLabel.Text = 'Sensor 2';
            app.Sensor2EditField = uieditfield(app.PiederechoPanel, 'numeric');
            app.Sensor3EditFieldLabel = uilabel(app.PiederechoPanel);
            app.Sensor3EditFieldLabel.Text = 'Sensor 3';
            app.Sensor3EditField = uieditfield(app.PiederechoPanel, 'numeric');
            app.Sensor4EditFieldLabel = uilabel(app.PiederechoPanel);
            app.Sensor4EditFieldLabel.Text = 'Sensor 4';
            app.Sensor4EditField = uieditfield(app.PiederechoPanel, 'numeric');
            app.PieizquierdoPanel = uipanel(app.SensoresfuerzaPanel_2);
            app.PieizquierdoPanel.Title = 'Pie izquierdo';
            app.Sensor1EditField_5Label = uilabel(app.PieizquierdoPanel);
            app.Sensor1EditField_5Label.Text = 'Sensor 1';
            app.Sensor1EditField_5 = uieditfield(app.PieizquierdoPanel, 'numeric');
            app.Sensor2EditField_2Label = uilabel(app.PieizquierdoPanel);
            app.Sensor2EditField_2Label.Text = 'Sensor 2';
            app.Sensor2EditField_2 = uieditfield(app.PieizquierdoPanel, 'numeric');
            app.Sensor3EditField_2Label = uilabel(app.PieizquierdoPanel);
            app.Sensor3EditField_2Label.Text = 'Sensor 3';
            app.Sensor3EditField_2 = uieditfield(app.PieizquierdoPanel, 'numeric');
            app.Sensor4EditField_2Label = uilabel(app.PieizquierdoPanel);
            app.Sensor4EditField_2Label.Text = 'Sensor 4';
            app.Sensor4EditField_2 = uieditfield(app.PieizquierdoPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end